classdef gui_renet_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        PAECMonogram         matlab.ui.control.Image
        PRLabMonogram        matlab.ui.control.Image
        COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel  matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        LoadImageButton      matlab.ui.control.Button
        OriginalImage        matlab.ui.control.Image
        Panel_2              matlab.ui.container.Panel
        GridLayout4          matlab.ui.container.GridLayout
        DetectionButton      matlab.ui.control.Button
        DetectionLabel       matlab.ui.control.Label
        DetectionLabelTitle  matlab.ui.control.Label
        PIEASMonogram        matlab.ui.control.Image
        PAICMonogram         matlab.ui.control.Image
        Label                matlab.ui.control.Label
    end

    
    properties (Access = public)
        original_image          % to store original image
        resized_image           % image after dwt preprocessing
        classification_model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = uifigure; d = uiprogressdlg(fig,'Title','Loading Classification Model','Indeterminate','on'); drawnow;
            load('models/net_RENet_VGG_Modified1.mat','Proposed_CB_STM_RENet');
            app.classification_model = net_RENet_VGG_Modified_1;
            delete(d); delete(fig);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.png'},'Select Image File');
            if class(filename) == "double"
                return;
            end
            
            app.original_image = imread([filepath, filename]);
            app.resized_image = imresize(app.original_image, [224, 224]);
            
            channels = size(size(app.resized_image));
            if channels(2) == 2
                app.resized_image = cat(3, app.resized_image, app.resized_image, app.resized_image);
            end
            
            app.OriginalImage.ImageSource = app.resized_image;
            app.DetectionLabelTitle.Text = "";
            app.DetectionButton.Enable = 1;
            app.DetectionLabel.Text = "Click Detection Button";
            app.DetectionLabel.FontColor = [0 0 0];
        end

        % Button pushed function: DetectionButton
        function DetectionButtonPushed(app, event)
            fig = uifigure; d = uiprogressdlg(fig,'Title','Running Classification Model','Indeterminate','on'); drawnow;
            [predicted_labels,posterior] = classify(app.classification_model,app.resized_image);
            delete(d); delete(fig);
            
            fprintf("posterior: %f\n", posterior);
            fprintf("predicted_labels: %f\n", predicted_labels);
            A = categorical(predicted_labels);
            if(A == "COVID-19")
                app.DetectionLabelTitle.Text = "Dangerous";
                app.DetectionLabel.Text = """You are COVID-19 Infected""";
                app.DetectionLabelTitle.FontColor = [1 0 0];
                app.DetectionLabel.FontColor = [1 0 0];
            else
                app.DetectionLabelTitle.Text = "Congratulations";
                app.DetectionLabel.Text = """You are Healthy""";
                app.DetectionLabelTitle.FontColor = [0 1 0];
                app.DetectionLabel.FontColor = [0 1 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PAECMonogram = uiimage(app.GridLayout);
            app.PAECMonogram.ImageSource = 'paec-logo.png';
            app.PRLabMonogram = uiimage(app.GridLayout);
            app.PRLabMonogram.ImageSource = 'pr-lab-logo.jpg';
            app.COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel = uilabel(app.GridLayout);
            app.COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel.Text = {'Coronavirus Disease Analysis using Chest X-ray '; 'Images and a Novel Deep Convolutional Neural Network'};
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.OriginalImage = uiimage(app.GridLayout2);
            app.OriginalImage.ImageSource = 'image-placeholder.png';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.DetectionButton = uibutton(app.GridLayout4, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Enable = 'off';
            app.DetectionButton.Text = 'Detection';
            app.DetectionLabel = uilabel(app.GridLayout4);
            app.DetectionLabel.Tag = 'DetectionLabel';
            app.DetectionLabel.Text = 'Click Detection Button';
            app.DetectionLabelTitle = uilabel(app.GridLayout4);
            app.DetectionLabelTitle.Text = '';
            app.PIEASMonogram = uiimage(app.GridLayout);
            app.PIEASMonogram.ImageSource = 'pieas-logo-black.jpg';
            app.PAICMonogram = uiimage(app.GridLayout);
            app.PAICMonogram.ImageSource = 'paic-logo.jpg';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Khan, Saddam Hussain, Anabia Sohail, Muhammad Mohsin Zafar, and Asifullah Khan. "Coronavirus Disease Analysis using Chest X-ray Images and a Novel Deep Convolutional Neural Network." (2020)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_renet_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end